classdef Draw_With_FT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        Calc_and_Draw_Button        matlab.ui.control.Button
        Save_File_CheckB            matlab.ui.control.CheckBox
        Draw_Lines_CheckB           matlab.ui.control.CheckBox
        Offset                      matlab.ui.control.NumericEditField
        MarginesLabel               matlab.ui.control.Label
        Delay_Slider                matlab.ui.control.Slider
        OpnienierysowaniaLabel      matlab.ui.control.Label
        Way_Of_Drawing              matlab.ui.control.DropDown
        SposbrysowaniaobrazuLabel   matlab.ui.control.Label
        PanelanimacjiLabel          matlab.ui.control.Label
        Change_ToEdit_Panel_Button  matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        Load_Image_Button           matlab.ui.control.Button
        Im_Modif_Fun_Button         matlab.ui.control.Button
        find_Contour_Button         matlab.ui.control.Button
        PanelobrbkizdjciaLabel      matlab.ui.control.Label
        Change_ToDraw_Panel_Button  matlab.ui.control.Button
        RozmycieSliderLabel         matlab.ui.control.Label
        median_slider               matlab.ui.control.Slider
        binarization_button         matlab.ui.control.Button
        OtwarcieLabel               matlab.ui.control.Label
        fill_slider                 matlab.ui.control.Slider
        ZamknicieLabel              matlab.ui.control.Label
        clean_slider                matlab.ui.control.Slider
        PrgSliderLabel              matlab.ui.control.Label
        threshold_val_slider        matlab.ui.control.Slider
        threshold_type              matlab.ui.container.ButtonGroup
        globalButton                matlab.ui.control.RadioButton
        adaptiveButton              matlab.ui.control.RadioButton
        PamitajObiektjestbiaytoczarneLabel  matlab.ui.control.Label
        negativeButton              matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Property % Description
        
        file_name = 0;
        path = 0;
        waiter = 0;
        
        % Data to write tabels of circles, radius and image info 
        image=0;
        imageMedian=0;
        imageBinary=0;
        imageFillClean=0;
        imageContour=0;
        cordsP=0;
        drawingP=0; 
        radiiP=0;
        setupP=0;
        
        cordsL=0;
        drawingL =0;
        radiiL=0;
        setupL=0;
        
        cordsS=0;
        drawingS =0;
        radiiS=0;
        setupS=0;
        %
        
        %strings in Polish:
        Str_choose_image = 'Wybierz obraz';
        Str_no_chosen_image = 'Nie wybrano obrazu';
        Str_way_of_drawing1 = 'kolejne obiekty po krawędziach';
        Str_way_of_drawing2 = 'pionowe kolumny od lewej';
        Str_way_of_drawing3 = 'spirala od środka obrazu';
        Str_no_image_to_calc = 'Najpierw wgraj obraz!';
        Str_no_binary_image = 'Najpierw przygotuj obraz!';
        Str_lots_of_pixels = 'Uwaga, ponad 15000 pikseli do narysowania!';
        Str_during_calc = 'Trwa oblicznie...';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Load_Image_Button
        function Load_Image_Button_Pushed(app, event)
            % cla(app.UIAxes) % clear image window
              
            [app.file_name,app.path] = uigetfile({'*.png';'*.jpeg';'*.jpg'}, app.Str_choose_image); % save file name and path
            
            if(app.file_name ~= 0)
              app.image = imread(fullfile(app.path,app.file_name));
              
              app.image = rgb2gray(app.image);
              app.imageMedian = app.image;
              imshow(app.image, 'Parent',app.UIAxes); %show chosen image
              
              app.imageBinary=0 ; % reset data (forget info about old Image)
              app.cordsP=0; 
              app.drawingP=0; 
              app.radiiP=0;
              app.setupP=0;
        
              app.cordsL=0;
              app.drawingL=0;
              app.radiiL=0;
              app.setupL=0;
        
              app.cordsS=0;
              app.drawingS=0;
              app.radiiS=0;
              app.setupS=0;
              
              app.threshold_type.Enable = 'on';
              app.threshold_val_slider.Enable = 'on';
              app.clean_slider.Enable = 'on';
              app.fill_slider.Enable = 'on';
              app.binarization_button.Enable = 'on';
              app.negativeButton.Enable = 'on';
              app.median_slider.Enable = 'on';
              app.find_Contour_Button.Enable = 'on';
              app.Im_Modif_Fun_Button.Enable = 'on';
              app.Change_ToDraw_Panel_Button.Enable = 'on';
              app.Change_ToEdit_Panel_Button.Enable = 'on';
            else
              uialert(app.UIFigure, app.Str_no_chosen_image, "Warning", 'Icon', 'warning');
            end
        end

        % Value changed function: Way_Of_Drawing
        function Way_Of_Drawing_ValueChanged(app, event)
            value = app.Way_Of_Drawing.Value;
            if(value(1) == app.Str_way_of_drawing1(1)) % Connecting pixels
               %  to line is useful only with first type of drawing
                app.Draw_Lines_CheckB.Visible = 'on';
                app.Draw_Lines_CheckB.Enable = 'on';
                app.Draw_Lines_CheckB.Value = 0;
            else
                app.Draw_Lines_CheckB.Visible = 'off';
                app.Draw_Lines_CheckB.Value = 0;
            end
        end

        % Button pushed function: Calc_and_Draw_Button
        function Calc_and_Draw_Button_Pushed(app, event)
        value = app.Way_Of_Drawing.Value;
        if app.file_name == 0 
           uialert(app.UIFigure, app.Str_no_image_to_calc, "Error");
        else
            % depend on chosen way of drawing image data will collected
            % in a diffrent order
            
            app.waiter = uiprogressdlg(app.UIFigure,'Title','Message',...
                     'Message',app.Str_during_calc,'Indeterminate','on');
            
            if value(1) == app.Str_way_of_drawing1(1)
                
                if(app.cordsL == 0)
                    app.cordsL = LoadByLine(app.imageContour);
                    [app.drawingL,app.radiiL, app.setupL] = CalculationsFourier(app.cordsL);
                end
            else
                if value(1) == app.Str_way_of_drawing2(1)
                    
                    if(app.cordsP == 0)
                        app.cordsP = LoadSimple(app.imageContour);
                        [app.drawingP,app.radiiP, app.setupP] = CalculationsFourier(app.cordsP);
                    end
                else
                    
                    if(app.cordsS == 0)
                        app.cordsS = LoadSpiral(app.imageContour);
                        [app.drawingS,app.radiiS, app.setupS] = CalculationsFourier(app.cordsS);
                    end 
                end
            end
            
            close(app.waiter); 
            
            % depend on chosen way of drawing and options 
            % draw image using proper data
            if value(1) == app.Str_way_of_drawing1(1)
               Animate(app.drawingL,app.radiiL,app.setupL,app.Offset.Value, app.Delay_Slider.Value,app.Save_File_CheckB.Value,app.Draw_Lines_CheckB.Value);   
            else
                if value(1) == app.Str_way_of_drawing2(1)
                    Animate(app.drawingP,app.radiiP,app.setupP,app.Offset.Value, app.Delay_Slider.Value,app.Save_File_CheckB.Value,app.Draw_Lines_CheckB.Value);
                else
                    Animate(app.drawingS,app.radiiS,app.setupS,app.Offset.Value, app.Delay_Slider.Value,app.Save_File_CheckB.Value,app.Draw_Lines_CheckB.Value);
                end
            end
            
        end
        
        end

        % Button pushed function: Change_ToDraw_Panel_Button
        function Change_ToDraw_Panel_Button_Pushed(app, event)
            if(app.imageBinary == 0)
                uialert(app.UIFigure, app.Str_no_binary_image, "Error");
            elseif (CheckPixels(app.imageContour) >15000)
                uialert(app.UIFigure, app.Str_lots_of_pixels, "Zbyt dużo pikseli");
            else
                % if image is ready for animation
                
                app.Change_ToDraw_Panel_Button.BackgroundColor = [1,0.5,0.5];
                app.Change_ToEdit_Panel_Button.BackgroundColor = [0.5,1,0.5];
                app.Load_Image_Button.Enable = 'off';
                app.Im_Modif_Fun_Button.Enable = 'off';
                app.find_Contour_Button.Enable = 'off';
                app.threshold_type.Enable = 'off';
                app.threshold_val_slider.Enable = 'off';
                app.clean_slider.Enable = 'off';
                app.negativeButton.Enable = 'off';
                app.fill_slider.Enable = 'off';
                app.binarization_button.Enable = 'off';
                app.median_slider.Enable = 'off';
                app.find_Contour_Button.Enable = 'off';
                app.Im_Modif_Fun_Button.Enable = 'off';
                
                app.Way_Of_Drawing.Enable = 'on';
                app.Way_Of_Drawing.Value = "kolejne obiekty po krawędziach";
                app.Draw_Lines_CheckB.Enable = 'on';
                app.Save_File_CheckB.Enable = 'on';
                app.Offset.Enable = 'on';
                app.Offset.Value = 0;
                app.Delay_Slider.Enable = 'on';
                app.Delay_Slider.Value = 0;
                app.Calc_and_Draw_Button.Enable = 'on';
            end
            
        end

        % Button pushed function: Change_ToEdit_Panel_Button
        function Change_ToEdit_Panel_Button_Pushed(app, event)
            
            app.Change_ToEdit_Panel_Button.BackgroundColor = [1,0.5,0.5];
            app.Change_ToDraw_Panel_Button.BackgroundColor = [0.5,1,0.5];
            app.Way_Of_Drawing.Enable = 'off';
            app.Draw_Lines_CheckB.Enable = 'off';
            app.Save_File_CheckB.Enable = 'off';
            app.Offset.Enable = 'off';
            app.Delay_Slider.Enable = 'off';
            app.Calc_and_Draw_Button.Enable = 'off';
            
            app.Load_Image_Button.Enable = 'on';
            app.Im_Modif_Fun_Button.Enable = 'on';
            app.find_Contour_Button.Enable = 'on';
            app.threshold_type.Enable = 'on';
            if (get(app.threshold_type.SelectedObject,'Tag')=="global")
                app.threshold_val_slider.Enable = 'on';
            end
            app.clean_slider.Enable = 'on';
            app.negativeButton.Enable = 'on';
            app.fill_slider.Enable = 'on';
            app.binarization_button.Enable = 'on';
            app.median_slider.Enable = 'on';
            app.find_Contour_Button.Enable = 'on';
            app.Im_Modif_Fun_Button.Enable = 'on';
            app.Change_ToDraw_Panel_Button.Enable = 'on';
            app.Change_ToEdit_Panel_Button.Enable = 'on';
            
            app.cordsP=0; 
            app.drawingP=0; 
            app.radiiP=0;
            app.setupP=0;
            app.cordsL=0;
            app.drawingL=0;
            app.radiiL=0;
            app.setupL=0;
            app.cordsS=0;
            app.drawingS=0;
            app.radiiS=0;
            app.setupS=0;
        end

        % Button pushed function: Im_Modif_Fun_Button
        function Im_Modif_Fun_Button_Pushed(app, event)
            if app.file_name == 0 
                uialert(app.UIFigure, app.Str_no_image_to_calc, "Error");
            else
                %imageGray = rgb2gray(app.image);
                app.imageBinary = imbinarize(app.image);
                %app.imageBinary = bwmorph(app.imageBinary,'skel',inf);
                app.imageFillClean = app.imageBinary;
                imshow(app.imageBinary, 'Parent',app.UIAxes);
            end
        end

        % Button pushed function: find_Contour_Button
        function find_Contour_ButtonPushed(app, event)
            if app.file_name == 0 
                uialert(app.UIFigure, app.Str_no_image_to_calc, "Error");
            else
                %im=rgb2gray(app.image);
                %im = ~imbinarize(im);
                im = app.imageFillClean;
                im = bwmorph(im,'remove');
                %bim=imerode(im,ones(2));
                %mim=imdilate(im,ones(2));
                %im = (mim-bim);
                %im = bwmorph(im,'thin',inf);
          
                app.imageContour = im;
                imshow(app.imageContour, 'Parent',app.UIAxes);                
            end
            
        end

        % Button pushed function: binarization_button
        function binarization_buttonButtonPushed(app, event)
            if app.file_name == 0 
                uialert(app.UIFigure, app.Str_no_image_to_calc, "Error");
            else
                if (get(app.threshold_type.SelectedObject,'Tag')=="global")
                    im = imbinarize(app.imageMedian, app.threshold_val_slider.Value);
                else
                    im = imbinarize(app.imageMedian, "adaptive");
                end
                im = imbinarize(app.imageMedian);
                app.imageBinary = im;
                app.imageFillClean = im;
                imshow(app.imageBinary, 'Parent',app.UIAxes);                
            end
        end

        % Value changing function: median_slider
        function median_sliderValueChanging(app, event)
            changingValue = round(event.Value);
            im = medfilt2(app.image, [changingValue changingValue]);
            app.imageMedian = im;
            imshow(im, 'Parent',app.UIAxes); 
        end

        % Value changing function: fill_slider
        function fill_sliderValueChanging(app, event)
            changingValue = round(event.Value);
            im = imopen(app.imageFillClean, ones(changingValue));
            imshow(im, 'Parent',app.UIAxes); 
        end

        % Value changing function: clean_slider
        function clean_sliderValueChanging(app, event)
            changingValue = round(event.Value);
            im = imclose(app.imageFillClean, ones(changingValue));
            imshow(im, 'Parent',app.UIAxes); 
        end

        % Selection changed function: threshold_type
        function threshold_typeSelectionChanged(app, event)
            selectedButton = app.threshold_type.SelectedObject;
            if (get(selectedButton,'Tag')=="global")
                im = imbinarize(app.imageMedian, app.threshold_val_slider.Value);
                app.threshold_val_slider.Enable = 'on';
            else
                im = imbinarize(app.imageMedian, "adaptive");
                app.threshold_val_slider.Enable = 'off';
            end
            im = imbinarize(app.imageMedian);
            app.imageBinary = im;
            app.imageFillClean = im;
            imshow(app.imageBinary, 'Parent',app.UIAxes);      
        end

        % Value changing function: threshold_val_slider
        function threshold_val_sliderValueChanging(app, event)
            im = imbinarize(app.imageMedian, event.Value);
            app.imageBinary = im;
            app.imageFillClean = im;
            imshow(app.imageBinary, 'Parent',app.UIAxes);
        end

        % Value changed function: clean_slider
        function clean_sliderValueChanged(app, event)
            value = round(app.clean_slider.Value);
            im = imclose(app.imageFillClean, ones(value));
            app.imageFillClean = im;
            imshow(app.imageFillClean, 'Parent',app.UIAxes); 
        end

        % Value changed function: fill_slider
        function fill_sliderValueChanged(app, event)
            value = round(app.fill_slider.Value);
            im = imopen(app.imageFillClean, ones(value));
            app.imageFillClean = im;
            imshow(app.imageFillClean, 'Parent',app.UIAxes); 
        end

        % Button pushed function: negativeButton
        function negativeButtonPushed(app, event)
            app.imageBinary = ~app.imageBinary;
            app.imageFillClean = app.imageBinary;
            imshow(app.imageBinary, 'Parent',app.UIAxes);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {774, 774};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {241, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Calc_and_Draw_Button = uibutton(app.LeftPanel, 'push');
            app.Calc_and_Draw_Button.ButtonPushedFcn = createCallbackFcn(app, @Calc_and_Draw_Button_Pushed, true);
            app.Calc_and_Draw_Button.Enable = 'off';
            app.Calc_and_Draw_Button.Text = 'Oblicz i rysuj';
            app.Save_File_CheckB = uicheckbox(app.LeftPanel);
            app.Save_File_CheckB.Enable = 'off';
            app.Save_File_CheckB.Text = 'zapisz animacje do pliku';
            app.Draw_Lines_CheckB = uicheckbox(app.LeftPanel);
            app.Draw_Lines_CheckB.Enable = 'off';
            app.Draw_Lines_CheckB.Text = 'połącz kropki';
            app.Offset = uieditfield(app.LeftPanel, 'numeric');
            app.Offset.Enable = 'off';
            app.MarginesLabel = uilabel(app.LeftPanel);
            app.MarginesLabel.Text = 'Margines:';
            app.Delay_Slider = uislider(app.LeftPanel);
            app.Delay_Slider.Limits = [0 1000];
            app.Delay_Slider.Enable = 'off';
            app.OpnienierysowaniaLabel = uilabel(app.LeftPanel);
            app.OpnienierysowaniaLabel.Text = 'Opóźnienie rysowania:      ';
            app.Way_Of_Drawing = uidropdown(app.LeftPanel);
            app.Way_Of_Drawing.Items = {'kolejne obiekty po krawędziach', 'pionowe kolumny od lewej', 'spirala od środka obrazu'};
            app.Way_Of_Drawing.ValueChangedFcn = createCallbackFcn(app, @Way_Of_Drawing_ValueChanged, true);
            app.Way_Of_Drawing.Enable = 'off';
            app.Way_Of_Drawing.Value = 'kolejne obiekty po krawędziach';
            app.SposbrysowaniaobrazuLabel = uilabel(app.LeftPanel);
            app.SposbrysowaniaobrazuLabel.Text = 'Sposób rysowania obrazu:';
            app.PanelanimacjiLabel = uilabel(app.LeftPanel);
            app.PanelanimacjiLabel.Text = 'Panel animacji';
            app.Change_ToEdit_Panel_Button = uibutton(app.LeftPanel, 'push');
            app.Change_ToEdit_Panel_Button.ButtonPushedFcn = createCallbackFcn(app, @Change_ToEdit_Panel_Button_Pushed, true);
            app.Change_ToEdit_Panel_Button.Enable = 'off';
            app.Change_ToEdit_Panel_Button.Text = 'Przejdź do panelu obróbki zdjęcia';
            app.RightPanel = uipanel(app.GridLayout);
            app.Load_Image_Button = uibutton(app.RightPanel, 'push');
            app.Load_Image_Button.ButtonPushedFcn = createCallbackFcn(app, @Load_Image_Button_Pushed, true);
            app.Load_Image_Button.Text = 'Wgraj zdjęcie';
            app.Im_Modif_Fun_Button = uibutton(app.RightPanel, 'push');
            app.Im_Modif_Fun_Button.ButtonPushedFcn = createCallbackFcn(app, @Im_Modif_Fun_Button_Pushed, true);
            app.Im_Modif_Fun_Button.Enable = 'off';
            app.Im_Modif_Fun_Button.Text = 'Automatyczna binaryzacja';
            app.find_Contour_Button = uibutton(app.RightPanel, 'push');
            app.find_Contour_Button.ButtonPushedFcn = createCallbackFcn(app, @find_Contour_ButtonPushed, true);
            app.find_Contour_Button.Enable = 'off';
            app.find_Contour_Button.Text = 'Konturuj';
            app.PanelobrbkizdjciaLabel = uilabel(app.RightPanel);
            app.PanelobrbkizdjciaLabel.Text = 'Panel obróbki zdjęcia';
            app.Change_ToDraw_Panel_Button = uibutton(app.RightPanel, 'push');
            app.Change_ToDraw_Panel_Button.ButtonPushedFcn = createCallbackFcn(app, @Change_ToDraw_Panel_Button_Pushed, true);
            app.Change_ToDraw_Panel_Button.Enable = 'off';
            app.Change_ToDraw_Panel_Button.Text = 'Przejdź do panelu animacji';
            app.RozmycieSliderLabel = uilabel(app.RightPanel);
            app.RozmycieSliderLabel.Text = 'Rozmycie';
            app.median_slider = uislider(app.RightPanel);
            app.median_slider.Limits = [1 100];
            app.median_slider.ValueChangingFcn = createCallbackFcn(app, @median_sliderValueChanging, true);
            app.median_slider.Enable = 'off';
            app.median_slider.Value = 1;
            app.binarization_button = uibutton(app.RightPanel, 'push');
            app.binarization_button.ButtonPushedFcn = createCallbackFcn(app, @binarization_buttonButtonPushed, true);
            app.binarization_button.Enable = 'off';
            app.binarization_button.Text = 'Binaryzacja';
            app.OtwarcieLabel = uilabel(app.RightPanel);
            app.OtwarcieLabel.Text = 'Otwarcie';
            app.fill_slider = uislider(app.RightPanel);
            app.fill_slider.Limits = [1 100];
            app.fill_slider.ValueChangedFcn = createCallbackFcn(app, @fill_sliderValueChanged, true);
            app.fill_slider.ValueChangingFcn = createCallbackFcn(app, @fill_sliderValueChanging, true);
            app.fill_slider.Enable = 'off';
            app.fill_slider.Value = 1;
            app.ZamknicieLabel = uilabel(app.RightPanel);
            app.ZamknicieLabel.Text = 'Zamknięcie';
            app.clean_slider = uislider(app.RightPanel);
            app.clean_slider.Limits = [1 100];
            app.clean_slider.ValueChangedFcn = createCallbackFcn(app, @clean_sliderValueChanged, true);
            app.clean_slider.ValueChangingFcn = createCallbackFcn(app, @clean_sliderValueChanging, true);
            app.clean_slider.Enable = 'off';
            app.clean_slider.Value = 1;
            app.PrgSliderLabel = uilabel(app.RightPanel);
            app.PrgSliderLabel.Text = 'Próg';
            app.threshold_val_slider = uislider(app.RightPanel);
            app.threshold_val_slider.Limits = [0 1];
            app.threshold_val_slider.ValueChangingFcn = createCallbackFcn(app, @threshold_val_sliderValueChanging, true);
            app.threshold_val_slider.Enable = 'off';
            app.threshold_type = uibuttongroup(app.RightPanel);
            app.threshold_type.SelectionChangedFcn = createCallbackFcn(app, @threshold_typeSelectionChanged, true);
            app.threshold_type.Enable = 'off';
            app.threshold_type.Title = 'Sposób binaryzacji';
            app.globalButton = uiradiobutton(app.threshold_type);
            app.globalButton.Tag = 'global';
            app.globalButton.Text = 'global';
            app.globalButton.Value = true;
            app.adaptiveButton = uiradiobutton(app.threshold_type);
            app.adaptiveButton.Tag = 'adaptive';
            app.adaptiveButton.Text = 'adaptive';
            app.PamitajObiektjestbiaytoczarneLabel = uilabel(app.RightPanel);
            app.PamitajObiektjestbiaytoczarneLabel.Text = 'Pamiętaj! Obiekt jest biały, tło czarne!';
            app.negativeButton = uibutton(app.RightPanel, 'push');
            app.negativeButton.ButtonPushedFcn = createCallbackFcn(app, @negativeButtonPushed, true);
            app.negativeButton.Enable = 'off';
            app.negativeButton.Text = 'Negacja';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Aktualny obraz')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Draw_With_FT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end